classdef userInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        loadSourceDataShortcut          matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ZdrojovedataTab                 matlab.ui.container.Tab
        ZobrazeniezjednejsadysnimokPanel  matlab.ui.container.Panel
        VytvormodelButton               matlab.ui.control.Button
        DostupnesadysnimokListBox       matlab.ui.control.ListBox
        DostupnesadysnimokListBoxLabel  matlab.ui.control.Label
        TypzobrazeniaDropDown           matlab.ui.control.DropDown
        TypzobrazeniaDropDownLabel      matlab.ui.control.Label
        ZobrazenieTab                   matlab.ui.container.Tab
        CenterPanel                     matlab.ui.container.Panel
        ViewerPanel                     matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        Panel2                          matlab.ui.container.Panel
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        XSlider                         matlab.ui.control.Slider
        XSliderLabel                    matlab.ui.control.Label
        PrehliadaniepovrstvachLabel     matlab.ui.control.Label
        ZAPVYPButton                    matlab.ui.control.StateButton
        ZrotSlider                      matlab.ui.control.Slider
        ZrotSliderLabel                 matlab.ui.control.Label
        YrotSlider                      matlab.ui.control.Slider
        YrotSliderLabel                 matlab.ui.control.Label
        XrotSlider                      matlab.ui.control.Slider
        XrotSliderLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        sourceData % Description
        viewer % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           [sourceContent,app.sourceData.Path,~]=loadSourceData(); 
           app.DostupnesadysnimokListBox.Items = {sourceContent(:).name};
           app.viewer=viewer3d();
        end

        % Button pushed function: VytvormodelButton
        function VytvormodelButtonPushed(app, event)
            single=makeModelSingle(app.sourceData.Path,app.DostupnesadysnimokListBox.Value,app.DostupnesadysnimokListBox.Value);
            zobraz=volshow(single,'Parent',app.viewer);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.loadSourceDataShortcut = uipushtool(app.Toolbar);
            app.loadSourceDataShortcut.Tooltip = {'Znovu načíta zdroje z priečinka sourceData.'};
            app.loadSourceDataShortcut.Icon = 'icon-mat-refresh.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ZdrojovedataTab = uitab(app.TabGroup);
            app.ZdrojovedataTab.Title = 'Zdrojove data';
            app.TypzobrazeniaDropDownLabel = uilabel(app.ZdrojovedataTab);
            app.TypzobrazeniaDropDownLabel.Text = 'Typ zobrazenia';
            app.TypzobrazeniaDropDown = uidropdown(app.ZdrojovedataTab);
            app.TypzobrazeniaDropDown.Items = {'single', '3D (SWI)'};
            app.TypzobrazeniaDropDown.Value = 'single';
            app.ZobrazeniezjednejsadysnimokPanel = uipanel(app.ZdrojovedataTab);
            app.ZobrazeniezjednejsadysnimokPanel.Title = 'Zobrazenie z jednej sady snimok';
            app.DostupnesadysnimokListBoxLabel = uilabel(app.ZobrazeniezjednejsadysnimokPanel);
            app.DostupnesadysnimokListBoxLabel.Text = 'Dostupne sady snimok';
            app.DostupnesadysnimokListBox = uilistbox(app.ZobrazeniezjednejsadysnimokPanel);
            app.DostupnesadysnimokListBox.Items = {'aaa'};
            app.DostupnesadysnimokListBox.Value = {};
            app.VytvormodelButton = uibutton(app.ZobrazeniezjednejsadysnimokPanel, 'push');
            app.VytvormodelButton.ButtonPushedFcn = createCallbackFcn(app, @VytvormodelButtonPushed, true);
            app.VytvormodelButton.Text = 'Vytvor model';
            app.ZobrazenieTab = uitab(app.TabGroup);
            app.ZobrazenieTab.Title = 'Zobrazenie';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ViewerPanel = uipanel(app.CenterPanel);
            app.ViewerPanel.Title = '3D Panel';
            app.RightPanel = uipanel(app.GridLayout);
            app.XrotSliderLabel = uilabel(app.RightPanel);
            app.XrotSliderLabel.Text = 'X rot';
            app.XrotSlider = uislider(app.RightPanel);
            app.YrotSliderLabel = uilabel(app.RightPanel);
            app.YrotSliderLabel.Text = 'Y rot';
            app.YrotSlider = uislider(app.RightPanel);
            app.ZrotSliderLabel = uilabel(app.RightPanel);
            app.ZrotSliderLabel.Text = 'Z rot';
            app.ZrotSlider = uislider(app.RightPanel);
            app.ZAPVYPButton = uibutton(app.RightPanel, 'state');
            app.ZAPVYPButton.Text = 'ZAP/VYP';
            app.PrehliadaniepovrstvachLabel = uilabel(app.RightPanel);
            app.PrehliadaniepovrstvachLabel.Text = 'Prehliadanie po vrstvach';
            app.Panel2 = uipanel(app.RightPanel);
            app.Panel2.Title = 'Panel2';
            app.XSliderLabel = uilabel(app.Panel2);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.Panel2);
            app.YSliderLabel = uilabel(app.Panel2);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.Panel2);
            app.ZSliderLabel = uilabel(app.Panel2);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.Panel2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end